classdef Transport_App_Kusel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        WerbekampagnefrFahrradStadtJenaLabel  matlab.ui.control.Label
        CommunicationCampaignSlider   matlab.ui.control.Slider
        Fahrradwegekmin2026EditFieldLabel  matlab.ui.control.Label
        Fahrradwegekmin2026EditField  matlab.ui.control.NumericEditField
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        GemeinschaftlicheFahrradeventsButton  matlab.ui.control.Button
        ZielgruppenspezifischeWerbekampagneButton  matlab.ui.control.Button
        FahrradzhlerinderStadtButton  matlab.ui.control.Button
        CO2ReduktionimVergleichzumJahr2023Label  matlab.ui.control.Label
        EinwohnendeinKuseldieFahrradfahrenLabel  matlab.ui.control.Label
        StarteSimulationButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StarteSimulationButton
        function StarteSimulationButtonPushed(app, event)
try
    %% --- Read inputs from UI ---
    rad_km_2026 = app.Fahrradwegekmin2026EditField.Value;
    gamma = app.CommunicationCampaignSlider.Value;

    if isfield(app.UIFigure.UserData, 'mu_v')
        mu_v = app.UIFigure.UserData.mu_v;
    else
        mu_v = 0;
    end

    if isfield(app.UIFigure.UserData, 'lambda')
        lambda = app.UIFigure.UserData.lambda;
    else
        lambda = 0.1;
    end

    if isfield(app.UIFigure.UserData, 'alpha_sensitivity')
        alpha_sensitivity = app.UIFigure.UserData.alpha_sensitivity;
    else
        alpha_sensitivity = 0.5;
    end

    %% --- Load calibration data ---
    opts = delimitedTextImportOptions("NumVariables",18);
    opts.DataLines=[1,Inf]; opts.Delimiter=",";
    opts.VariableNames=["Var1","Var2","Var3","Var4","Var5","Var6","Var7","Var8","B","PNB","NE","EE","Var13","Var14","Var15","Var16","Var17","Var18"];
    opts.SelectedVariableNames=["B","PNB","NE","EE"];
    opts.VariableTypes=["string","string","string","string","string","string","string","string","double","double","double","double","string","string","string","string","string","string"];
    AllData = readtable("AllData.csv",opts);

    B = table2array(AllData(2:end,1));
    PNB = table2array(AllData(2:end,2));
    NE = table2array(AllData(2:end,3));
    PNB=(PNB-1)/5; B=(B-1)/6; NE=(NE-1)/5;
    data=[B PNB NE]; mu=mean(data); Sigma=cov(data);

    %% --- Model constants ---
    n = 1000; T = 1000; md = 10; mo = 10; beta = 0.01; epsilon = 0.01; rev = 0.1;
    rad_km_2023 = 90.4;

    %% --- Set alpha ---
  
alpha_old = 1- (rad_km_2023 / 1000);
alpha_new = 1- (rad_km_2026 / 1000);
change = (alpha_new/ alpha_old);
alpha_change = change * alpha_new;
alpha_norm = alpha_change / (alpha_change + 1);

% Set sensitivitiy 
alpha_sensitivity = 0.5; 

% Adjust alpha 
alpha = ((1 - alpha_sensitivity) * alpha_new) + (alpha_sensitivity * alpha_norm);

fprintf('Adjusted behavioral threshold alpha: %.3f (was %.3f)\n', alpha);

    %% --- Initialize population ---
    init = rmvnrnd(mu, Sigma, n, [eye(3); -eye(3)], [ones(3,1); zeros(3,1)]);
    init(:,1) = rand(n,1) < init(:,1);

    %% --- Run ABM (must be on MATLAB path) ---
    [x, ~, ~] = ABM(n, md, mo, beta, gamma, lambda, alpha, epsilon, rev, init(:,1), init(:,2), init(:,3), mu_v, T);
    share_bike = sum(x(:,end)) / n;

    %% --- CO₂ calculation (over time, % baseline) --


    pkm_2023 = struct('walk',3670.0,'bike',3710.0,'car',44837.0,'pp',4156.0);
    ef = struct('bike',8,'car',144.2,'pp',90.1,'walk',0);

    co2_time = zeros(1, T);

    % baseline
    co2_base = pkm_2023.bike*ef.bike + pkm_2023.car*ef.car + pkm_2023.pp*ef.pp;

    for t = 1:T
        bike_share_t = sum(x(:,t))/n;
        new_bike_pkm = pkm_2023.bike * (1 + bike_share_t);
        delta_bike_t = new_bike_pkm - pkm_2023.bike;
        new_car_pkm = max(pkm_2023.car - delta_bike_t, 0);
        new_pp_pkm = pkm_2023.pp;

        co2_total_t = new_bike_pkm*ef.bike + new_car_pkm*ef.car + new_pp_pkm*ef.pp;
        co2_time(t) = 100 * (1 - (co2_total_t / co2_base)); % in percent
    end

    % Final CO₂ emissions and labels
    co2_final = co2_time(end);
    app.EinwohnendeinKuseldieFahrradfahrenLabel.Text = sprintf('Einwohnende in Kusel, \ndie Fahrrad fahren: %.2f %%', share_bike*100);
    app.CO2ReduktionimVergleichzumJahr2023Label.Text = sprintf(['CO2 Reduktion im Vergleich\n zum Jahr 2023: %.1f %%'], co2_final);

    % --- Plot bike behavior over time ---
plot(app.UIAxes, 1:T, sum(x)/n, 'b', 'LineWidth', 2);
title(app.UIAxes, 'Fahrradnutzung über Zeit');
xlabel(app.UIAxes, 'Zeit');
ylabel(app.UIAxes, 'Anteil Fahrradfahrer');
grid(app.UIAxes, 'on');

% --- Plot CO₂ emissions over time (% baseline) ---
plot(app.UIAxes2, 1:T, co2_time, 'g', 'LineWidth', 2);
title(app.UIAxes2, 'CO₂-Emissionen Reduktion (% im Vergleich zu 2023)');
xlabel(app.UIAxes2, 'Zeit');
ylabel(app.UIAxes2, 'CO₂ [%]');
grid(app.UIAxes2, 'on');


catch ME
    uialert(app.UIFigure, ME.message, 'Simulation Error');
    disp(getReport(ME))
end


        end

        % Button pushed function: FahrradzhlerinderStadtButton
        function FahrradzhlerinderStadtButtonPushed(app, event)
                app.UIFigure.UserData.mu_v = 0.5;
                uialert(app.UIFigure, ...
        'Fahrradzähler in der Stadt aufgestellt (mu_v = 0.5)', ...
        'Information');
        end

        % Button pushed function: ZielgruppenspezifischeWerbekampagneButton
        function ZielgruppenspezifischeWerbekampagneButtonPushed(app, event)
                app.UIFigure.UserData.lambda = 0.05;
                uialert(app.UIFigure, ...
        'Zielgruppenspezifische Werbekampagnen eingeführt (lambda = 0.05)', ...
        'Information');
        end

        % Button pushed function: GemeinschaftlicheFahrradeventsButton
        function GemeinschaftlicheFahrradeventsButtonPushed(app, event)
                app.UIFigure.UserData.alpha_sensitivity = 0.7;
                uialert(app.UIFigure, ...
        'Gemeinschaftliche Fahrradevents finden statt (alpha_sensitivity = 0.7)', ...
        'Information');    
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'CO2 Emissionen')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Fahrradnutzung')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StarteSimulationButton = uibutton(app.GridLayout, 'push');
            app.StarteSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StarteSimulationButtonPushed, true);
            app.StarteSimulationButton.Text = 'Starte Simulation';
            app.EinwohnendeinKuseldieFahrradfahrenLabel = uilabel(app.GridLayout);
            app.EinwohnendeinKuseldieFahrradfahrenLabel.Text = {'Einwohnende in Kusel,'; 'die Fahrrad fahren'};
            app.CO2ReduktionimVergleichzumJahr2023Label = uilabel(app.GridLayout);
            app.CO2ReduktionimVergleichzumJahr2023Label.Text = {'CO2 Reduktion im Vergleich'; 'zum Jahr 2023'};
            app.FahrradzhlerinderStadtButton = uibutton(app.GridLayout, 'push');
            app.FahrradzhlerinderStadtButton.ButtonPushedFcn = createCallbackFcn(app, @FahrradzhlerinderStadtButtonPushed, true);
            app.FahrradzhlerinderStadtButton.Text = 'Fahrradzähler in der Stadt';
            app.ZielgruppenspezifischeWerbekampagneButton = uibutton(app.GridLayout, 'push');
            app.ZielgruppenspezifischeWerbekampagneButton.ButtonPushedFcn = createCallbackFcn(app, @ZielgruppenspezifischeWerbekampagneButtonPushed, true);
            app.ZielgruppenspezifischeWerbekampagneButton.Text = {'Zielgruppenspezifische'; 'Werbekampagne'};
            app.GemeinschaftlicheFahrradeventsButton = uibutton(app.GridLayout, 'push');
            app.GemeinschaftlicheFahrradeventsButton.ButtonPushedFcn = createCallbackFcn(app, @GemeinschaftlicheFahrradeventsButtonPushed, true);
            app.GemeinschaftlicheFahrradeventsButton.Text = {'Gemeinschaftliche '; 'Fahrradevents'};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Biking_files', 'adventure-street-travel-and-bike-break-outdoor-in-royalty-free-image-1695135852.jpg');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Co2_files', 'Carbon-Emissions-Climbed-Less-Than-Feared-in-2022-Despite-Coal-Resurgence.jpg');
            app.Fahrradwegekmin2026EditField = uieditfield(app.GridLayout, 'numeric');
            app.Fahrradwegekmin2026EditFieldLabel = uilabel(app.GridLayout);
            app.Fahrradwegekmin2026EditFieldLabel.Text = 'Fahrradwege (km) in 2026 ';
            app.CommunicationCampaignSlider = uislider(app.GridLayout);
            app.CommunicationCampaignSlider.Step = 0.1;
            app.WerbekampagnefrFahrradStadtJenaLabel = uilabel(app.GridLayout);
            app.WerbekampagnefrFahrradStadtJenaLabel.Text = {'Werbekampagne'; 'Fahrrad-Stadt Kusel'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transport_App_Kusel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end